classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NetlistMenu                matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        netfileMenu                matlab.ui.container.Menu
        xlsxfileMenu               matlab.ui.container.Menu
        GraphsMenu                 matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        ListBoxTouchstoneFiles     matlab.ui.control.ListBox
        TouchstoneFilesLabel       matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        TabGroup                   matlab.ui.container.TabGroup
        RectangularTab             matlab.ui.container.Tab
        PlotoptionsDropDown        matlab.ui.control.DropDown
        PlotoptionsDropDownLabel   matlab.ui.control.Label
        PolarTab                   matlab.ui.container.Tab
        SmithChartTab              matlab.ui.container.Tab
        ControlPanel               matlab.ui.container.Panel
        ZoEditFieldLabel_2         matlab.ui.control.Label
        ZoEditField                matlab.ui.control.NumericEditField
        ZoEditFieldLabel           matlab.ui.control.Label
        PortnumberEditField        matlab.ui.control.NumericEditField
        PortnumberEditFieldLabel   matlab.ui.control.Label
        StepEditFieldLabel_2       matlab.ui.control.Label
        TouchtoneFileoptionsPanel  matlab.ui.container.Panel
        FormatDropDown             matlab.ui.control.DropDown
        FormatDropDownLabel        matlab.ui.control.Label
        OutputfileEditField        matlab.ui.control.EditField
        OutputfileEditFieldLabel   matlab.ui.control.Label
        InputfileEditField         matlab.ui.control.EditField
        InputfileEditFieldLabel    matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
        DropDownEndFreq            matlab.ui.control.DropDown
        DropDownStartFreq          matlab.ui.control.DropDown
        StepEditField              matlab.ui.control.NumericEditField
        StepEditFieldLabel         matlab.ui.control.Label
        EndFreqEditField           matlab.ui.control.NumericEditField
        EndFreqEditFieldLabel      matlab.ui.control.Label
        StartFreqEditField         matlab.ui.control.NumericEditField
        StartFreqEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        net_fileName
        net_filePath
        net_file string {mustBeFile(net_file)} 
        xslx_fileName
        xslx_filePath
        xslx_file string {mustBeFile(xslx_file)} % Full path to the file
        Netlist_table 
        Netlist_CellArray
        ZMatrix
        YMatrix
        ABCD_Matrix
        SMatrix
        Start_Freq_Fld
        End_Freq_Fld
        Start_Freq
        End_Freq
        Start_Freq_Unit
        End_Freq_Unit
        Frequencies
        Step
        Zo
        Port_Num
        Run_Valid = false;
        Warning_Msg
        Parameters_Ready = false;
        Touchstone_Out_File
        Touchstone_Out_File_Name
        Touchstone_Out_File_Path
        Touchstone_Out_File_Format
        Touchstone_Input_File
        Touchstone_Input_File_Name
        Touchstone_Input_File_Path
        SMatrix_Touchstone
        %hsm = smithplot(fig1,freq,GammaL,'LineWidth',2.0,'Color','m','View','bottom-right','LegendLabels',{'#Gamma L'});
        %h = heatmap(app.UIFigure,rand(10));
        %ax = uiaxes(parent,Name,Value) specifies UIAxes property values using one or more Name,Value arguments.
    end
    
    events (NotifyAccess = private)
        PathChanged 
        xlsxNetlistChanged
    end
    
    methods (Access = private)
        
        function Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num) 

            %UPDATE Saul: 'If' condition to get the Z parameter matrix of a single
            %element.

            %calculate the number of nodes for the matrix size
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            Nodes = unique(allNodesConn,'sorted'); 
            Nodes = Nodes(2:end); %Remove the reference node N0 (assumed it will always be named as that an therefore be at the top of the arrray)
            NodesNum = length(Nodes); 
            Nodes_Matrix = zeros(NodesNum,NodesNum); %Se crea una matriz de NxN donde N es la cantidad de puertos
            Y_Parameter_Matrix = zeros(Port_Num,Port_Num); %Double check 
            Z_Parameter_Matrix = zeros(Port_Num,Port_Num);
            Z_Matrix = zeros(Port_Num,Port_Num, Step);
            Step_num = 0; 


            for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                Step_num = Step_num + 1;
                Nodes_Matrix = Nodes_Matrix_Fun(app,Netlist_CellArray,F); %Nodes_Matrix_fun function

                for i=1 : Port_Num
                    RedRow_Nodes_Matrix = Nodes_Matrix;  %Esta igualacion se usa para guardar la matriz de Nodes_Matrix temporalmente y en la siguiente linea se 
                    RedRow_Nodes_Matrix(i,:) = [];          %elimina un renglon, esto es para crear las matrices reducidas 11, 12,  21, etc.
                
                    for j = 1 : Port_Num
                        RedCol_Nodes_Matrix = RedRow_Nodes_Matrix;                  
                        RedCol_Nodes_Matrix(:,j) = [];      %lo mismo que en el caso anterior pero para eliminar columnas
                
                        Y_Parameter_Matrix(i,j) = (det(Nodes_Matrix)/det(RedCol_Nodes_Matrix));  %Port admitance (since it is a nodes matriz), meaning Y parameter (escalar value)
                
                         if j ~= i
                            if (rem(i,2) == 1 && rem(j,2) == 0) || (rem(i,2) == 0 && rem(j,2) == 1)  
                                Z_Parameter_Matrix(i,j) =  -1/Y_Parameter_Matrix(i,j);            
                             else 
                                 Z_Parameter_Matrix(i,j) =  1/Y_Parameter_Matrix(i,j);
                             end
                         else %j==i
                            Z_Parameter_Matrix(i,j) =  1/Y_Parameter_Matrix(i,j);        %Conversion de Parameter Y a Z diagonal principal.
                         end
                    end
                
                end
        
                Z_Matrix(:,:,Step_num) = Z_Parameter_Matrix;
                %Y_Matrix(:,:,Step_num) = Y_Parameter_Matrix;
            end
        end
        
        function Nodes_Matrix = Nodes_Matrix_Fun(app, Netlist_CellArray, Freq) 
            Size_NodesCellArray = size(Netlist_CellArray);

            %calculate the number of nodes for the matrix size
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            Nodes = unique(allNodesConn,'sorted'); 
            Nodes = Nodes(2:end); %Remove the reference node N0 (assumed it will always be named as that an therefore be at the top of the arrray)
            NodesNum = length(Nodes); 
            Nodes_Matrix = zeros(NodesNum,NodesNum); %Se crea una matriz de NxN donde N es la cantidad de puertos
            
            for i = 1 : NodesNum                                  %Iteracion en la cantidad de nodos para los renglones
                for j = 1 : NodesNum                              %Iteracion en la cantidad de nodos para las columas
                    for k = 1: Size_NodesCellArray(1,1)           %Iteracion para determinar los componentes en respectivo punto de la matriz nodal   
            
                        if strcmpi(Nodes(i),Nodes(j))             %Diagonal principal de la matriz de nodos
                            if strcmpi(Nodes(j),Netlist_CellArray(k,2)) || strcmpi(Nodes(j),Netlist_CellArray(k,3))    %siempre que el nodo en analisis este presente se sumara                
                                Nodes_Matrix(i,j) = Nodes_Matrix(i,j) + 1/(Calc_Impedance(app,Netlist_CellArray{k,4},Netlist_CellArray{k,5},Freq));
                            end
                        else  %diagonales los elementos que interactuan con el nodo principal y no principal
                            if (strcmpi(Nodes(j),Netlist_CellArray(k,2)) || strcmpi(Nodes(j),Netlist_CellArray(k,3))) && (strcmpi(Nodes(i),Netlist_CellArray(k,2)) || strcmpi(Nodes(i),Netlist_CellArray(k,3)))
                                Nodes_Matrix(i,j) = Nodes_Matrix(i,j) - 1/(Calc_Impedance(app,Netlist_CellArray{k,4},Netlist_CellArray{k,5},Freq));
                            end
                        end
            
                    end    
                end
            end
        end

        function Z = Calc_Impedance(app, Elem_Type, Value, Freq)
            switch Elem_Type
                case 'R'
                    Z = Value;
                case 'L'
                    Z = 2*1i*pi*Value*Freq;
                case 'C'
                    Z = 1/(2*1i*pi*Freq*Value);
            end
        end
        
        function Freq_Value = Calc_Freq_Value(app, Freq_Field_Value, Freq_Unit)
            switch Freq_Unit
                case 'Hz'
                    Freq_Value = Freq_Field_Value; 
                case 'KHz'
                    Freq_Value = Freq_Field_Value*10^3; 
                case 'MHz' 
                    Freq_Value = aFreq_Field_Value*10^6; 
                case 'GHz'
                    Freq_Value = Freq_Field_Value*10^9; 
                case 'THz'
                    Freq_Value = Freq_Field_Value*10^12; 
            end
        end
        
        function Retrieve_Values_For_Run(app) %Function called everytime run boton is pressed
            app.Start_Freq_Fld = app.StartFreqEditField.Value;
            app.End_Freq_Fld = app.EndFreqEditField.Value;
            app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); %app.StartFreqEditField.Value
            app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value); %app.EndFreqEditField.Value
            app.Step = app.StepEditField.Value;
            app.Zo = app.ZoEditField.Value;
            app.Port_Num = app.PortnumberEditField.Value;
            app.Frequencies = linspace(app.Start_Freq, app.End_Freq, app.Step); %linspace(x1,x2,n) generates n points. The spacing between the points is (x2-x1)/(n-1).
            app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
            app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
        end
        
        function Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num)
            
            %UPDATE Saul: 'If' condition to get the Z parameter matrix of a single
            %element.

            Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
            Y_Matrix = pageinv(Z_Matrix);
        end
        
        function ABCD_Matrix = ABCD_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num)
             if Port_Num == 2
                 Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                 ABCD_Matrix = z2abcd(Z_Matrix);
             else
                 disp("ABCD parameters are only available for 2 port networks");
                 app.Warning_Msg = msgbox("ABCD parameters are only available for 2 port networks");
                 ABCD_Matrix = 0;
             end
        end

        
        function Netlist_CellArray = Netlist2CellArray(app, netlist_file)

            fileID= fopen(netlist_file,'r','n','UTF-8');
            
            n=1;
            while(~feof(fileID))
                nline = fgetl(fileID);
                if(~contains(nline,'.') && ~contains(nline,'*')) 
                   splitted_nline = strsplit(nline);
                   NumVal= sip2num(splitted_nline{:,4});
                   
                   %Define the type of element from the component name.
                   %Note: Components must be named starting with the letter
                   %corresponding to the type of element their are, ej.
                   %Capacitor --> C1
                   elem_Type = splitted_nline{:,1};
                   elem_Type = elem_Type(1,1);
            
                   %Create custom cell array from netlist info that follows
                   %the excell format.
                   %Name, 1st Node, 2nd Node, type, Value
                   Netlist_CellArray(n,:) = [splitted_nline{:,1}, splitted_nline{:,2}, splitted_nline{:,3}, {elem_Type}, {NumVal}]; 
                   n=n+1;
                end
            end
        end   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: xlsxfileMenu
        function xlsxfileMenuSelected(app, event)
            [app.xslx_fileName,app.xslx_filePath] = uigetfile('*.xlsx');
            if isequal(app.xslx_fileName,0)
                disp('User selected Cancel');
                app.Run_Valid = false;
            else
                disp(['User selected ', fullfile(app.xslx_filePath,app.xslx_fileName)]);
                app.xslx_file = fullfile(app.xslx_filePath,app.xslx_fileName);
                app.Netlist_table = readtable(app.xslx_file); 
                app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value'}; % names of columns
                app.UITable.Data = app.Netlist_table;
                app.Netlist_table.Properties.VariableDescriptions{1} = 'Name'; 
                app.Netlist_table.Properties.VariableDescriptions{2} = '1st Node';
                app.Netlist_table.Properties.VariableDescriptions{3} = '2nd Node';
                app.Netlist_table.Properties.VariableDescriptions{4} = 'Type';
                app.Netlist_table.Properties.VariableDescriptions{5} = 'Value';
                app.UITable.ColumnName = app.Netlist_table.Properties.VariableDescriptions;
                %app.Netlist_CellArray = table2cell(app.Netlist_table); 
                app.Run_Valid = true;
            end

            %notify(app,"xlsxNetlistChanged");
            %app.FileName.Value = [fileName,filePath];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Retrieve_Values_For_Run(app);
            if app.Run_Valid

                progDlg = uiprogressdlg(app.UIFigure,'Title','Simulator info', 'Message','Start of simulation');
                pause(.5)

                % Perform calculations
                progDlg.Value = .33; 
                progDlg.Message = 'Loading data';
                pause(.5)

                % Perform calculations
                progDlg.Value = .67;
                progDlg.Message = 'Calculating circuit parameters';
                pause(.5)
     
                app.ZMatrix = Z_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                app.YMatrix = Y_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); %This could be improved to only pass as an argment the Zmatrix
                app.ABCD_Matrix = ABCD_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num);
                app.SMatrix = z2s(app.ZMatrix,app.Zo);

                % Finish calculations
                progDlg.Value = 1;
                progDlg.Message = 'Simulation complete';
                pause(.5)
            
                % Close dialog box
                close(progDlg) 

                app.Parameters_Ready = true;
            else 
                app.Warning_Msg = msgbox("Please load a netlist before running a simulation","Warning: Missing netlist");
                app.Parameters_Ready = false;
            end
        end

        % Value changed function: StartFreqEditField
        function StartFreqEditFieldValueChanged(app, event)
            app.Start_Freq_Fld = app.StartFreqEditField.Value;
        end

        % Value changed function: EndFreqEditField
        function EndFreqEditFieldValueChanged(app, event)
            app.End_Freq_Fld = app.EndFreqEditField.Value;
        end

        % Value changed function: DropDownStartFreq
        function DropDownStartFreqValueChanged(app, event)
            app.Start_Freq = Calc_Freq_Value(app, app.StartFreqEditField.Value, app.DropDownStartFreq.Value);
        end

        % Value changed function: DropDownEndFreq
        function DropDownEndFreqValueChanged(app, event)
            app.End_Freq = Calc_Freq_Value(app, app.EndFreqEditField.Value, app.DropDownEndFreq.Value);
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            app.Step = app.StepEditField.Value;
        end

        % Value changed function: PortnumberEditField
        function PortnumberEditFieldValueChanged(app, event)
            app.Port_Num = floor(app.PortnumberEditField.Value);
            app.PortnumberEditField.Value = app.Port_Num;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.Parameters_Ready
                if contains(app.Touchstone_Out_File_Name,'.s')
                    app.Touchstone_Out_File_Name = split(app.Touchstone_Out_File_Name,'.');
                    app.Touchstone_Out_File_Name = app.Touchstone_Out_File_Name{1};
                end
                String_Port_Num = fi(app.Port_Num).Value;
                app.Touchstone_Out_File_Name = strcat(app.Touchstone_Out_File_Name,'.s',String_Port_Num,'p');
                File_Filter = split(app.Touchstone_Out_File_Name,'.');
                File_Filter = strcat('*.',File_Filter(2));
                [app.Touchstone_Out_File_Name, app.Touchstone_Out_File_Path] = uiputfile(File_Filter,'Select the directory to save the file',app.Touchstone_Out_File_Name); %Save at...
                app.OutputfileEditField.Value = app.Touchstone_Out_File_Name; %name includes format .s*p, yet if user changes the format while on the save window, it migt have an erratic behavior, migth need to implement the above to correct the file name.

                if isequal(app.Touchstone_Out_File_Name,0) || isequal(app.Touchstone_Out_File_Path,0)
                   disp('User clicked Cancel.')
                else
                   disp(['User selected ',fullfile(app.Touchstone_Out_File_Path,app.Touchstone_Out_File_Name),' and then clicked Save.'])
                end
                %test_parameters = sparameters(app.SMatrix,app.Frequencies,app.Zo)
                %rfwrite(app.SMatrix,app.Frequencies,fullfile(app.Touchstone_Out_File_Path, app.Touchstone_Out_File_Name), 'FrequencyUnit', app.DropDownEndFreq.Value, 'ReferenceResistance', app.Zo, 'Format', app.Touchstone_Out_File_Format, 'ForceOverwrite', true); %rfwrite(data, freq, 'defaultnew.s2p'), or for Y parameter rfwrite(Y50, 'passive.y2p','FrequencyUnit', 'MHz');
                app.Touchstone_Out_File = fullfile(app.Touchstone_Out_File_Path, app.Touchstone_Out_File_Name);
                rfwrite(app.SMatrix,app.Frequencies,app.Touchstone_Out_File, 'FrequencyUnit', app.DropDownEndFreq.Value, 'ReferenceResistance', app.Zo, 'Format', app.Touchstone_Out_File_Format, 'ForceOverwrite', true); %rfwrite(data, freq, 'defaultnew.s2p'), or for Y parameter rfwrite(Y50, 'passive.y2p','FrequencyUnit', 'MHz');
            else 
                app.Warning_Msg = msgbox("Please run a simulation before exporting file","Warning: No simulation was ran");
            end
        end

        % Value changed function: OutputfileEditField
        function OutputfileEditFieldValueChanged(app, event)
            app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
        end

        % Value changed function: ZoEditField
        function ZoEditFieldValueChanged(app, event)
            app.Zo = app.ZoEditField.Value;
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Method to read a touchstone file
            [app.Touchstone_Input_File_Name,app.Touchstone_Input_File_Path] = uigetfile('*.*');
            if isequal(app.Touchstone_Input_File_Name,0) 
                disp('User selected Cancel');
            else
                if contains(app.Touchstone_Input_File_Name,'.s')
                    app.Touchstone_Input_File = fullfile(app.Touchstone_Input_File_Path,app.Touchstone_Input_File_Name);
                    disp(['User selected ', app.Touchstone_Input_File]);
                    app.SMatrix_Touchstone = sparameters(app.Touchstone_Input_File); %SMatrix_Touchstone is a S-parameter object
                    app.InputfileEditField.Value = app.Touchstone_Input_File_Name;
                    app.ListBoxTouchstoneFiles.Items = [app.ListBoxTouchstoneFiles.Items, {app.Touchstone_Input_File_Name}]; %horizontal concatenation
                    app.ListBoxTouchstoneFiles.ItemsData = [app.ListBoxTouchstoneFiles.ItemsData, {app.SMatrix_Touchstone}];
                else
                    disp('Invalid file, must select a .s*p file');
                    app.Warning_Msg = msgbox("Invalid file, must select a .s*p file","Warning: Invalid input file");
                    app.Touchstone_Input_File = '';
                    app.InputfileEditField.items = app.Touchstone_Input_File;
                end
            end
        end

        % Menu selected function: netfileMenu
        function netfileMenuSelected(app, event)
            [app.net_fileName,app.net_filePath] = uigetfile('*.net'); %Files .cir could also be read
            if isequal(app.net_fileName,0)
                disp('User selected Cancel');
                app.Run_Valid = false;
            else
                disp(['User selected ', fullfile(app.net_filePath,app.net_fileName)]);
                app.net_file = fullfile(app.net_filePath,app.net_fileName);
                app.Netlist_CellArray = Netlist2CellArray(app, app.net_file); 
                app.Netlist_table = cell2table(app.Netlist_CellArray);
                app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value'};
                app.UITable.Data = app.Netlist_table;
                app.Run_Valid = true;
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetlistMenu = uimenu(app.UIFigure);
            app.NetlistMenu.Text = 'Netlist';
            app.OpenMenu = uimenu(app.NetlistMenu);
            app.OpenMenu.Text = 'Open';
            app.netfileMenu = uimenu(app.OpenMenu);
            app.netfileMenu.MenuSelectedFcn = createCallbackFcn(app, @netfileMenuSelected, true);
            app.netfileMenu.Text = '.net file';
            app.xlsxfileMenu = uimenu(app.OpenMenu);
            app.xlsxfileMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxfileMenuSelected, true);
            app.xlsxfileMenu.Text = '.xlsx file';
            app.GraphsMenu = uimenu(app.UIFigure);
            app.GraphsMenu.Text = 'Graphs';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.StartFreqEditFieldLabel = uilabel(app.ControlPanel);
            app.StartFreqEditFieldLabel.Text = 'Start Freq';
            app.StartFreqEditField = uieditfield(app.ControlPanel, 'numeric');
            app.StartFreqEditField.ValueChangedFcn = createCallbackFcn(app, @StartFreqEditFieldValueChanged, true);
            app.EndFreqEditFieldLabel = uilabel(app.ControlPanel);
            app.EndFreqEditFieldLabel.Text = 'End Freq';
            app.EndFreqEditField = uieditfield(app.ControlPanel, 'numeric');
            app.EndFreqEditField.ValueChangedFcn = createCallbackFcn(app, @EndFreqEditFieldValueChanged, true);
            app.EndFreqEditField.Value = 1;
            app.StepEditFieldLabel = uilabel(app.ControlPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.ControlPanel, 'numeric');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Value = 1;
            app.DropDownStartFreq = uidropdown(app.ControlPanel);
            app.DropDownStartFreq.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.DropDownStartFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownStartFreqValueChanged, true);
            app.DropDownStartFreq.Value = 'Hz';
            app.DropDownEndFreq = uidropdown(app.ControlPanel);
            app.DropDownEndFreq.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.DropDownEndFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownEndFreqValueChanged, true);
            app.DropDownEndFreq.Value = 'Hz';
            app.RunButton = uibutton(app.ControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TouchtoneFileoptionsPanel = uipanel(app.ControlPanel);
            app.TouchtoneFileoptionsPanel.Title = 'Touchtone File options';
            app.Button = uibutton(app.TouchtoneFileoptionsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'folderIcon.png');
            app.Button.Text = '';
            app.SaveButton = uibutton(app.TouchtoneFileoptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InputfileEditFieldLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.InputfileEditFieldLabel.Text = 'Input file';
            app.InputfileEditField = uieditfield(app.TouchtoneFileoptionsPanel, 'text');
            app.InputfileEditField.Editable = 'off';
            app.OutputfileEditFieldLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.TouchtoneFileoptionsPanel, 'text');
            app.OutputfileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfileEditFieldValueChanged, true);
            app.OutputfileEditField.Value = 'parameters';
            app.FormatDropDownLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.TouchtoneFileoptionsPanel);
            app.FormatDropDown.Items = {'RI', 'MA', 'DB'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'RI';
            app.StepEditFieldLabel_2 = uilabel(app.ControlPanel);
            app.StepEditFieldLabel_2.Text = 'Hz';
            app.PortnumberEditFieldLabel = uilabel(app.ControlPanel);
            app.PortnumberEditFieldLabel.Text = 'Port number';
            app.PortnumberEditField = uieditfield(app.ControlPanel, 'numeric');
            app.PortnumberEditField.RoundFractionalValues = 'on';
            app.PortnumberEditField.ValueChangedFcn = createCallbackFcn(app, @PortnumberEditFieldValueChanged, true);
            app.PortnumberEditField.Value = 2;
            app.ZoEditFieldLabel = uilabel(app.ControlPanel);
            app.ZoEditFieldLabel.Text = 'Zo';
            app.ZoEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ZoEditField.ValueChangedFcn = createCallbackFcn(app, @ZoEditFieldValueChanged, true);
            app.ZoEditField.Value = 50;
            app.ZoEditFieldLabel_2 = uilabel(app.ControlPanel);
            app.ZoEditFieldLabel_2.Text = 'Ω';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.RectangularTab = uitab(app.TabGroup);
            app.RectangularTab.Title = 'Rectangular';
            app.PlotoptionsDropDownLabel = uilabel(app.RectangularTab);
            app.PlotoptionsDropDownLabel.Text = 'Plot options';
            app.PlotoptionsDropDown = uidropdown(app.RectangularTab);
            app.PlotoptionsDropDown.Items = {'Mag-Phase', 'Real-Img'};
            app.PlotoptionsDropDown.Value = 'Mag-Phase';
            app.PolarTab = uitab(app.TabGroup);
            app.PolarTab.Title = 'Polar';
            app.SmithChartTab = uitab(app.TabGroup);
            app.SmithChartTab.Title = 'Smith Chart';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Name'; '1st Node'; '2nd Node'; 'Type'; 'Value'};
            app.UITable.RowName = {};
            app.TouchstoneFilesLabel = uilabel(app.GridLayout3);
            app.TouchstoneFilesLabel.Text = 'Touchstone Files';
            app.ListBoxTouchstoneFiles = uilistbox(app.GridLayout3);
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end